classdef Audio_Equalizer < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                      matlab.ui.Figure
        AxisPanel                     matlab.ui.container.Panel
        OutputFreqAxes                matlab.ui.control.UIAxes
        InputFreqAxes                 matlab.ui.control.UIAxes
        OutputTimeAxes                matlab.ui.control.UIAxes
        InputTimeAxes                 matlab.ui.control.UIAxes
        SamplingRateEditField_2       matlab.ui.control.NumericEditField
        SamplingRateEditField_2Label  matlab.ui.control.Label
        BandtoPlotDropDown            matlab.ui.control.DropDown
        BandtoPlotDropDownLabel       matlab.ui.control.Label
        LocationEditField             matlab.ui.control.EditField
        LocationEditFieldLabel        matlab.ui.control.Label
        PlayButton                    matlab.ui.control.Button
        doubleFsButton                matlab.ui.control.Button
        halfFsButton                  matlab.ui.control.Button
        ModeButtonGroup               matlab.ui.container.ButtonGroup
        CustomButton                  matlab.ui.control.RadioButton
        StandardButton                matlab.ui.control.RadioButton
        EnterButton                   matlab.ui.control.Button
        Panel                         matlab.ui.container.Panel
        gain_20k                      matlab.ui.control.Slider
        KHzLabel_4                    matlab.ui.control.Label
        gain_170                      matlab.ui.control.Slider
        HzLabel                       matlab.ui.control.Label
        PlotsPanel                    matlab.ui.container.Panel
        GridLayout                    matlab.ui.container.GridLayout
        UIAxes_PZ                     matlab.ui.control.UIAxes
        UIAxes_Impulse                matlab.ui.control.UIAxes
        UIAxes_Phase                  matlab.ui.control.UIAxes
        UIAxes_Mag                    matlab.ui.control.UIAxes
        FrequencyBandGainsPanel       matlab.ui.container.Panel
        gain_300                      matlab.ui.control.Slider
        Label                         matlab.ui.control.Label
        gain_610                      matlab.ui.control.Slider
        HzLabel_2                     matlab.ui.control.Label
        gain_6k                       matlab.ui.control.Slider
        KHzLabel_3                    matlab.ui.control.Label
        gain_12k                      matlab.ui.control.Slider
        KHzLabel_2                    matlab.ui.control.Label
        gain_14k                      matlab.ui.control.Slider
        KhzLabel                      matlab.ui.control.Label
        ResetButton                   matlab.ui.control.Button
        gain_3k                       matlab.ui.control.Slider
        KHzLabel                      matlab.ui.control.Label
        gain_1005                     matlab.ui.control.Slider
        HzLabel_3                     matlab.ui.control.Label
        PlotButton                    matlab.ui.control.Button
        SaveButton                    matlab.ui.control.Button
        ApplyButton                   matlab.ui.control.Button
        FilterTypeButtonGroup         matlab.ui.container.ButtonGroup
        IIRButton                     matlab.ui.control.RadioButton
        FIRButton                     matlab.ui.control.RadioButton
        BrowseButton                  matlab.ui.control.Button
    end

    
    properties (Access = private)
        y % resampled wave
        fir_order = 40
        iir_order = 4
        fs % sampling frequency
        t % time
        fm % 
        fo % sampling rate output
        Ns % number of samples
        BandGains = ones(1,9); % gains of each band
        BandEdges = [0,200,500,800,1200,3000,6000,12000,16000,20000];    
        newfs % inputted sampling rate
        new_signal % Description
        Mode="Standard" %App Mode: Standard or Custom
        CustomBands;
        CustomGains;
        FilterType;
        FilterTypeFIR;
        FilterTypeIIR;
        FilterOrder;
        FilterSubType;
        OutputSignal;

    end
    
    methods (Access = private)
        
        function x=showCustomBandDialog(app)
           % Create a new figure (popup)
        d = uifigure('Name', 'Enter Custom Bands', 'Position', [500 500 300 200]);
    
        % Label
        uilabel(d, ...
            'Text', 'Enter frequency edges (comma-separated):', ...
            'Position', [20 130 260 30]);
    
        % Input field
        bandField = uieditfield(d, ...
            'Position', [20 100 260 25], ...
            'Tag', 'BandField');
    
        % Confirm Button
        uibutton(d, ...
            'Text', 'Confirm', ...
            'Position', [100 50 100 30], ...
            'ButtonPushedFcn', @(btn,event)confirmBands(app, d, bandField)); 
        x=d;
            end
            
        function confirmBands(app, dialog, bandField)
                str = bandField.Value;
        edges = str2num(str);  % Parse comma-separated input
    
        % Validation
            if isempty(edges) || edges(1) ~= 0 || edges(end) ~= 20000 || ...
           length(edges) < 6 || length(edges) > 11 || any(diff(edges) <= 0)
            uialert(dialog, 'Input must be 0–20kHz, 5–10 bands, ascending.', 'Invalid Input');
            return;
            end

            % Store the bands and close dialog
            app.CustomBands = edges;
            disp("Custom bands accepted:");
            disp(edges);
            close(dialog);
        end

        function showCustomGainDialogBox(app)
            numBands = length(app.CustomBands) - 1;
            prompts = cell(1, numBands);  % Preallocate
            default_vals = cell(1, numBands);

            for i = 1:numBands
                prompt{i} = sprintf('Enter gain for band %d (dB):', i);
                default_vals{i} = '0';
            end
            dlg_title = 'Gain (in dB)';
            num_lines = 1;
            
            answer = inputdlg(prompts, dlg_title, num_lines, default_vals);
            if isempty(answer)
                errordlg('Gain input canceled by user.');
                return;
            end

            gains = zeros(1, length(answer));
            for i = 1:length(answer)
                val = str2double(answer{i});  % Convert String to A number tto store the gain
                if isnan(val)
                    errordlg(sprintf('Invalid gain input at band %d', i));
                    return;
                end
                gains(i) = val;
            end
            app.CustomGains=gains;
            disp("Custom bands accepted:");
            disp(gains);
        end

  function filterTypeIIR = getIIRFilterType(app)
    % Loop to ensure the user selects a valid filter type
     validFilters = {'BUTTERWORTH', 'CHEBYSHEV I', 'CHEBYSHEV II'};
            while true
                prompt = {'Select IIR filter type (Butterworth, Chebyshev I, Chebyshev II):'};
                dlg_title = 'IIR Filter Type';
                num_lines = 1;
                default_answer = {'Butterworth'};  % Default value
                answer = inputdlg(prompt, dlg_title, num_lines, default_answer);
                
                if isempty(answer)
                    filterTypeIIR = ''; % No selection made, exit
                    return;
                end
        
        % Extract the filter type from the input dialog
        filterTypeIIR = answer{1};
        
        % Convert user input to uppercase to avoid case sensitivity issues
        filterTypeIIR = strtrim(filterTypeIIR); % Remove leading/trailing spaces
       
        
        % Check if the selected filter type is valid (case-insensitive)
                 if ismember(upper(filterTypeIIR), validFilters)
                    break;  % Exit the loop if the filter type is valid
                else
            % Display an error dialog and prompt the user to try again
            h= errordlg('Invalid Filter Type. Please select a valid filter.', 'Error');
            waitfor(h);
                end
            end
    
    % Continue with the selected valid filter type
    disp(['Selected IIR Filter Type: ', filterTypeIIR]);
        end
        
      function filterTypeFIR = getFIRFilterType(app)
    % Loop to ensure the user selects a valid filter type
     validFilters = {'HAMMING', 'HANNING', 'BLACKMAN'};
            while true
                prompt = {'Select FIR filter type (Hamming, Hanning, or Blackman):'};
                dlg_title = 'FIR Filter Type';
                num_lines = 1;
                default_answer = {'Hamming'};  % Default value
                answer = inputdlg(prompt, dlg_title, num_lines, default_answer);
                
                if isempty(answer)
                    filterTypeFIR = ''; % No selection made, exit
                    return;
                end
        
        % Extract the filter type from the input dialog
        filterTypeFIR = answer{1};
        
        % Convert user input to uppercase to avoid case sensitivity issues
        filterTypeFIR = strtrim(filterTypeFIR); % Remove leading/trailing spaces
       
        
        % Check if the selected filter type is valid (case-insensitive)
                 if ismember(upper(filterTypeFIR), validFilters)
                    break;  % Exit the loop if the filter type is valid
                else
            % Display an error dialog and prompt the user to try again
            h= errordlg('Invalid Filter Type. Please select a valid filter.', 'Error');
            waitfor(h);
                end
            end
    
    % Continue with the selected valid filter type
    disp(['Selected FIR Filter Type: ', filterTypeFIR]);
      end
       function filterOrder = getFilterOrder(app)
   
            while true
                prompt = {'Enter filter order (positive integer):'};
                dlg_title = 'Filter Order';
                num_lines = 1;
                default_answer = {'5'};  % Default value
                answer = inputdlg(prompt, dlg_title, num_lines, default_answer);
                
                if isempty(answer)
                    filterOrder=[];
                    return;
                end
        
        
                orderVal = str2double(answer{1});
        
                if isnumeric(orderVal) && isfinite(orderVal) && orderVal > 0 && mod(orderVal, 1) == 0
                filterOrder = orderVal;
                return;
                else
                 h = errordlg('Invalid filter order. Please enter a positive integer.', 'Error');
                 waitfor(h);  % Wait until user closes error dialog
                end
            end
       end
       function filters = designIIRFilters(app)
        numBands = length(app.CustomBands) - 1;
        filters = cell(1, numBands);
        
            for i = 1:numBands
            bandStart = app.CustomBands(i);       % Start frequency of the band
            bandEnd = app.CustomBands(i + 1);     % End frequency of the band
            
            % Design an IIR filter for each band
            if i == 1
                [b, a] = butter(4, bandEnd / (app.fs / 2));  % 4th order lowpass filter
            elseif i > 1 && i < numBands
                [b, a] = butter(4, [bandStart, bandEnd] / (app.fs / 2), 'bandpass');
            else
                [b, a] = butter(4, bandStart / (app.fs / 2), 'high');  % Highpass filter
            end
            
            % Store the filter coefficients
            filters{i} = struct('b', b, 'a', a, 'startFreq', bandStart, 'endFreq', bandEnd);
            end
       end

       function plot(app)
  
            t = linspace(0, length(app.y)/app.fs, length(app.y));
            L = length(app.y);  
        
            plot(app.InputTimeAxes, t, app.y);
            xlabel(app.InputTimeAxes, 'Time (s)');
            ylabel(app.InputTimeAxes, 'Amplitude');
            title(app.InputTimeAxes, 'Input Signal - Time Domain');

          
            t_new = (0:L-1) / (app.newfs );
            
            plot(app.OutputTimeAxes, t_new, app.y);
            xlabel(app.OutputTimeAxes, 'Time (s)');
            ylabel(app.OutputTimeAxes, 'Amplitude');
            title(app.OutputTimeAxes, 'Output Signal - Time Domain');

            
            % Frequency domain input signal
            f_axis_in = linspace(-app.fs/2, app.fs/2, L);
            Y_fft = fftshift(fft(app.y)/L);
            fmag = abs(Y_fft);
        
            plot(app.InputFreqAxes, f_axis_in, fmag);
            xlabel(app.InputFreqAxes, 'Frequency (Hz)');
            ylabel(app.InputFreqAxes, 'Magnitude');
            title(app.InputFreqAxes, 'Input Signal - Frequency Domain');
                   
             % Frequency domain output signal
            f_axis_out = linspace(-app.newfs/2, app.newfs/2, L);
            Y_out_fft = fftshift(fft(app.y)/L);
            fmag_out = abs(Y_out_fft);
        
            plot(app.OutputFreqAxes, f_axis_out, fmag_out);
            xlabel(app.OutputFreqAxes, 'Frequency (Hz)');
            ylabel(app.OutputFreqAxes, 'Magnitude');
            title(app.OutputFreqAxes, 'Output Signal - Frequency Domain');
       end

       function applyFilter(app, bandToAnalyze)
           % Generate test tones
%app.fs = 44100; % Sampling rate
%duration = 3; % seconds
%t = 0:1/app.fs:duration;

% Create multi-frequency signal
%freqs = [250 1000 4000]; % Low, mid, high frequencies
%app.y = sum(sin(2*pi*freqs'.*t), 1)'; % Column vector

% Configure bands
app.BandEdges = [0 300 3000 app.fs/2]; % Band boundaries
app.BandGains = [0 6 0]; % Only pass mid band (300-3000Hz)

    % Step 1: Validate sampling rate
    if isempty(app.fs) || app.fs <= 0
        app.fs = 44100; % Default sampling rate
    end
    nyquist = app.fs / 2;
    
    % Initialize output signal
    outputSignal = zeros(size(app.y));
    
    % Validate band configuration
    if length(app.BandEdges) ~= length(app.BandGains) + 1
        error('BandEdges must have one more element than BandGains.');
    end
    
    % Loop over each frequency band
    for i = 1:length(app.BandGains)
        % Get band edges
        f_low = app.BandEdges(i);
        f_high = app.BandEdges(i+1);
        
        % Validate frequency range
        if f_low < 0 || f_high > nyquist || f_low >= f_high
            error(['Invalid frequency range for band ', num2str(i), ...
                  ': [', num2str(f_low), ', ', num2str(f_high), ...
                  ']. Must satisfy 0 ≤ f_low < f_high ≤ ', num2str(nyquist)]);
        end
        
        % Normalize frequencies with safety checks
        if f_low == 0
            % Lowpass case
            Wn = f_high / nyquist;
            if Wn >= 1
                Wn = 0.9999; % Prevent exactly 1
            end
        else
            % Bandpass case
            Wn = [f_low, f_high] / nyquist;
            if Wn(2) >= 1
                Wn(2) = 0.9999; % Prevent exactly 1
            end
        end
        
        % Ensure Wn is within (0,1)
        Wn = max(min(Wn, 0.9999), 0.0001);
        
        % Set filter subtype
        if strcmp(app.FilterType, 'FIR')
            app.FilterSubType = app.FilterTypeFIR;
        else
            app.FilterSubType = app.FilterTypeIIR;
        end
        
        % Design filter
        [b, a] = app.designBandFilter(app.FilterType, app.FilterSubType, Wn, app.FilterOrder);
        
        % Analyze selected band
        if i == bandToAnalyze
            disp(['Analyzing selected band: ', num2str(i)]);
            app.plotFilterCharacteristics(b, a);
        end

        % Apply filter and gain
        bandSignal = filter(b, a, app.y) * app.BandGains(i);
        outputSignal = outputSignal + bandSignal;
    end
    
    % Store final output
    app.OutputSignal = outputSignal;
end

      function [b, a] = designBandFilter(app, ~, subtype, Wn, order)
    % Validate inputs
    if nargin < 5
        error('Not enough input arguments');
    end
    
    disp(['Filter Type: ', app.FilterType]);
    disp(['Order: ', num2str(order)]);
    
    if strcmp(app.FilterType, 'FIR')
        % FIR filter design using window method
        windowType = lower(subtype); % Convert to lowercase for case-insensitive comparison
        
        % Check if the window type is valid
        validWindows = {'hamming', 'hanning', 'blackman'};
        if ~any(strcmp(windowType, validWindows))
            error('Invalid window type. Choose from: hamming, hanning, blackman');
        end
        
        % Determine filter type based on Wn
        if isscalar(Wn)
            filterType = 'low'; % Lowpass if single cutoff frequency
        else
            filterType = 'bandpass'; % Bandpass if two frequencies
        end
        
        % Design the FIR filter with the specified window
        switch windowType
            case 'hamming'
                b = fir1(order, Wn, filterType, hamming(order+1));
            case 'hanning'
                b = fir1(order, Wn, filterType, hanning(order+1));
            case 'blackman'
                b = fir1(order, Wn, filterType, blackman(order+1));
            otherwise
                b = fir1(order, Wn, filterType); % Default rectangular window
        end
        
        a = 1; % FIR filters have no denominator coefficients
        
    elseif strcmp(app.FilterType, 'IIR')
        % IIR filter design (Butterworth, Chebyshev, etc.)
        if isscalar(Wn)
            filterType = 'low';
        else
            filterType = 'bandpass';
        end
        
        if strcmp(subtype, 'Butterworth')
            [b, a] = butter(order, Wn, filterType);
        elseif strcmp(subtype, 'Chebyshev I')
            [b, a] = cheby1(order, 1, Wn, filterType); % 1 dB ripple
        elseif strcmp(subtype, 'Chebyshev II')
            [b, a] = cheby2(order, 20, Wn, filterType); % 20 dB stopband
        else
            error('Unknown IIR subtype. Choose from: Butterworth, Chebyshev I, Chebyshev II');
        end
    else
        error('Unknown filter type. Choose from: FIR, IIR');
    end
end

function type = getFilterType(~,Wn)
    if isscalar(Wn)
        type = 'low';
    else
        type = 'bandpass';
    end
end
function plotFilterCharacteristics(app, b, a)
    % 1. Frequency response
    [H, w] = freqz(b, a, 1024, app.fs);
    
    % Magnitude Response (in dB)
    plot(app.UIAxes_Mag, w, 20*log10(abs(H)));
    title(app.UIAxes_Mag, 'Magnitude Response (dB)');
    xlabel(app.UIAxes_Mag, 'Frequency (Hz)');
    ylabel(app.UIAxes_Mag, 'Magnitude (dB)');
    grid(app.UIAxes_Mag, 'on');
    
    % Phase Response (unwrapped)
    plot(app.UIAxes_Phase, w, unwrap(angle(H)));
    title(app.UIAxes_Phase, 'Phase Response');
    xlabel(app.UIAxes_Phase, 'Frequency (Hz)');
    ylabel(app.UIAxes_Phase, 'Phase (radians)');
    grid(app.UIAxes_Phase, 'on');

    % 2. Combined Impulse and Step response plot
    % Get impulse response
    [h, n] = impz(b, a, 100);
    
    % Get step response
    u = ones(100, 1);
    s = filter(b, a, u);
    
    % Clear previous plot and plot both responses
    cla(app.UIAxes_Impulse);
    hold(app.UIAxes_Impulse, 'on');
    
    % Plot impulse response in blue
    stem(app.UIAxes_Impulse, n, h, 'filled', 'MarkerSize', 4, 'LineWidth', 1.5, 'Color', [0 0.4470 0.7410]);
    
    % Plot step response in orange
    plot(app.UIAxes_Impulse, 0:99, s, 'LineWidth', 1.5, 'Color', [0.8500 0.3250 0.0980]);
    
    % Adjust axes
    y_margin = 0.1 * (max([abs(h); abs(s)]) + eps);
    ylim(app.UIAxes_Impulse, [min([h; s])-y_margin, max([h; s])+y_margin]);
    xlim(app.UIAxes_Impulse, [-0.5, 99.5]);
    
    title(app.UIAxes_Impulse, 'Impulse & Step Response');
    xlabel(app.UIAxes_Impulse, 'Sample Index (n)'); 
    ylabel(app.UIAxes_Impulse, 'Amplitude');
    legend(app.UIAxes_Impulse, 'Impulse', 'Step', 'Location', 'best');
    grid(app.UIAxes_Impulse, 'on');
    hold(app.UIAxes_Impulse, 'off');

    % 4. Pole-zero plot
    zplane(b, a, app.UIAxes_PZ);
    title(app.UIAxes_PZ, 'Pole-Zero Plot');
    grid(app.UIAxes_PZ, 'on');
end
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: BrowseButton
        function BrowseButtonPushed(app, event)
           [FileName,FilePath]=uigetfile({'*.wav'});
           fullPath = [FilePath FileName];
           app.LocationEditField.Value = fullPath;
           [app.y,app.fm] = audioread(app.LocationEditField.Value);
           fprintf('The value of Sampling Frequency is %.2f\n', app.fm);
           app.y = app.y(:,1);   
           app.y = transpose(app.y);
           app.Ns = length(app.y);
           app.t = linspace(0, app.Ns/app.fm, app.Ns);
           %app.fm = app.fs/2;

        end

        % Button pushed function: ApplyButton
        function ApplyButtonPushed(app, event)

            gains = [app.gain_170.Value app.gain_300.Value app.gain_610.Value app.gain_1005.Value app.gain_3k.Value app.gain_6k.Value app.gain_12k.Value app.gain_14k.Value app.gain_20k.Value];  
            disp(['Gains ', num2str(gains)]);
            app.BandGains = db2mag(gains);
                                      
            x =  str2double(app.BandtoPlotDropDown.Value);
            bandToAnalyze = str2double(app.BandtoPlotDropDown.Value); % e.g., "3"
            disp(['Selected band index for analysis: ', num2str(bandToAnalyze)]);
            app.applyFilter(bandToAnalyze);
            
        end

        % Callback function
        function samplingrateEditFieldValueChanged(app, event)

            
        end

        % Callback function
        function ResetButtonPushed(app, event)
           
        end

        % Button pushed function: PlotButton
        function PlotButtonPushed(app, event)

    % Check if output signal exists
    if isempty(app.OutputSignal)
        errordlg('No output signal to plot. Apply filtering first.', 'Error');
        return;
    end
    
    % Get sampling frequency (assuming it's stored in app.fs)
    fs = app.fs;  
    N = length(app.OutputSignal);  % Number of samples
    
    % Time vector for plotting
    t = (0:N-1) / fs;  
    
    %% 1. Time Domain Plots
    % Clear previous plots
    cla(app.InputTimeAxes);
    cla(app.OutputTimeAxes);
    
    % Plot input signal (time domain)
    plot(app.InputTimeAxes, t, app.y, 'b', 'LineWidth', 1.2);
    title(app.InputTimeAxes, 'Input Signal (Time Domain)');
    xlabel(app.InputTimeAxes, 'Time (s)');
    ylabel(app.InputTimeAxes, 'Amplitude');
    grid(app.InputTimeAxes, 'on');
    
    % Plot output signal (time domain)
    plot(app.OutputTimeAxes, t, app.OutputSignal, 'r', 'LineWidth', 1.2);
    title(app.OutputTimeAxes, 'Output Signal (Time Domain)');
    xlabel(app.OutputTimeAxes, 'Time (s)');
    ylabel(app.OutputTimeAxes, 'Amplitude');
    grid(app.OutputTimeAxes, 'on');
    
    %% 2. Frequency Domain Plots (FFT)
    % Compute FFT of input and output signals
    f = (0:N-1) * (fs / N);  % Frequency vector
    fftInput = abs(fft(app.y));
    fftOutput = abs(fft(app.OutputSignal));
    
    % Normalize FFT magnitudes
    fftInput = fftInput / max(fftInput);
    fftOutput = fftOutput / max(fftOutput);
    
    % Clear previous frequency plots
    cla(app.InputFreqAxes);
    cla(app.OutputFreqAxes);
    
    % Plot input signal (frequency domain)
    plot(app.InputFreqAxes, f(1:N/2), 20*log10(fftInput(1:N/2)), 'b', 'LineWidth', 1.2);
    title(app.InputFreqAxes, 'Input Signal (Frequency Domain)');
    xlabel(app.InputFreqAxes, 'Frequency (Hz)');
    ylabel(app.InputFreqAxes, 'Magnitude (dB)');
    grid(app.InputFreqAxes, 'on');
    
    % Plot output signal (frequency domain)
    plot(app.OutputFreqAxes, f(1:N/2), 20*log10(fftOutput(1:N/2)), 'r', 'LineWidth', 1.2);
    title(app.OutputFreqAxes, 'Output Signal (Frequency Domain)');
    xlabel(app.OutputFreqAxes, 'Frequency (Hz)');
    ylabel(app.OutputFreqAxes, 'Magnitude (dB)');
    grid(app.OutputFreqAxes, 'on');
    
    %% 3. Adjust Axes for Better Visualization
    % Set x-axis limits to avoid clutter
    xlim(app.InputTimeAxes, [0, t(end)]);
    xlim(app.OutputTimeAxes, [0, t(end)]);
    xlim(app.InputFreqAxes, [0, fs/2]);
    xlim(app.OutputFreqAxes, [0, fs/2]);
    
    % Auto-scale y-axes for time-domain plots
    ylim(app.InputTimeAxes, 'auto');
    ylim(app.OutputTimeAxes, 'auto');
    
                  
        end

        % Callback function
        function ApplynewfiltersButtonPushed(app, event)
    
        end

        % Button pushed function: SaveButton
        function SaveButtonPushed(app, event)
           
    
            if isempty(app.OutputSignal)
                errordlg('No output signal to save. Process the signal first.', 'Error');
                return;
            end
        
            % Ask user for a filename and location
            [filename, pathname] = uiputfile(...
                {'*.wav', 'WAV Audio File (*.wav)'; ...
                 '*.mat', 'MATLAB Data File (*.mat)'}, ...
                'Save Output Signal', ...
                'output_composite.wav');
        
            % If user cancels, exit
            if isequal(filename, 0) || isequal(pathname, 0)
                return;
            end
        
            % Full file path
            filepath = fullfile(pathname, filename);
        
            % Save based on file extension
            [~, ~, ext] = fileparts(filename);
            switch lower(ext)
                case '.wav'
            % Normalize to [-1, 1] range for WAV files
            compositeSignal = app.OutputSignal / max(abs(app.OutputSignal(:)));
            audiowrite(filepath, compositeSignal, app.fs);  % Use original sampling rate (app.fs)
            
            % Optional: Save half/double sample rate versions
            audiowrite(strrep(filepath, '.wav', '_half.wav'), compositeSignal, app.fs/2);
            audiowrite(strrep(filepath, '.wav', '_double.wav'), compositeSignal, app.fs*2);
            
            msgbox('Composite signal saved as WAV file(s).', 'Success');
                otherwise
            errordlg('Unsupported file format.', 'Error');
            end     
        end

        % Button pushed function: doubleFsButton
        function doubleFsButtonPushed(app, event)
            app.newfs = app.fs*2;
            sound(app.y, app.newfs );
            disp("Playing signal at double the sampling frequency...");

            app.plot();
           
            
        end

        % Button pushed function: halfFsButton
        function halfFsButtonPushed(app, event)

            app.newfs = app.fs / 2;
       
        % Play the signal at half the original sampling frequency
        sound(app.y, app.newfs);

         app.plot();
        
                
        end

        % Callback function
        function SamplingRateEditFieldValueChanged(app, event)

          
        end

        % Button pushed function: EnterButton
        function EnterButtonPushed(app, event)
 % Ensure the display format is integer (%.0f)
    app.SamplingRateEditField_2.ValueDisplayFormat = '%.0f';
    
    % Get the value from the Edit Field
    value = app.SamplingRateEditField_2.Value;
    
    % Check if the value is valid
    if ~isnumeric(value) || isnan(value) || value <= 0 || ~isscalar(value)
        % Show error dialog
        uialert(app.UIFigure, ...
            'Sampling rate must be a positive, numeric scalar (e.g., 44100).', ...
            'Invalid Input', ...
            'Icon', 'error');
        
        % Reset to default (44.1 kHz)
        app.SamplingRateEditField_2.Value = 44100;
        app.fs = 44100;  % Explicitly update app.fs
    else
        % Round to nearest integer (in case of decimal input)
        app.fs = round(value);
        
        % Update the Edit Field to show the rounded value
        app.SamplingRateEditField_2.Value = app.fs;
        
        % Log the new sampling rate
        fprintf('Output Sampling Rate set to: %d Hz\n', app.fs);
    end
        end

        % Callback function
        function SamplingRateEditField_2ValueChanged(app, event)
          
            
        end

        % Value changed function: SamplingRateEditField_2
        function SamplingRateEditField_2ValueChanged2(app, event)
        
        end

        % Selection changed function: ModeButtonGroup
        function ModeButtonGroupSelectionChanged(app, event)
            selectedButton = app.ModeButtonGroup.SelectedObject;
                if selectedButton == app.StandardButton
        % User selected Standard mode
        disp("Standard Mode Selected");
        app.Mode = "Standard";
        
             elseif selectedButton == app.CustomButton
        % User selected Custom mode
        disp("Custom Mode Selected");
        app.Mode = "Custom";
        x=app.showCustomBandDialog();
        waitfor(x);
        app.showCustomGainDialogBox();
        end
          
        end

        % Selection changed function: FilterTypeButtonGroup
        function FilterTypeButtonGroupSelectionChanged(app, event)
            selectedFilter = app.FilterTypeButtonGroup.SelectedObject;
                if selectedFilter == app.FIRButton
                 app.FilterType = 'FIR';
                 disp("FIR filter Selected");
                 app.FilterTypeFIR=app.getFIRFilterType();
                 elseif selectedFilter == app.IIRButton
                disp("IIR filter Selected");
                app.FilterType = 'IIR';
                app.FilterTypeIIR = app.getIIRFilterType();
                end

              app.FilterOrder=app.getFilterOrder();
        end

        % Value changed function: BandtoPlotDropDown
        function BandtoPlotDropDownValueChanged(app, event)
            value = app.BandtoPlotDropDown.Value;

            cla(app.UIAxes_Mag);    % Clear magnitude response plot
            cla(app.UIAxes_Phase);  % Clear phase response plot
            cla(app.UIAxes_Impulse);% Clear impulse response plot
            cla(app.UIAxes_Impulse);   % Clear step response plot
            cla(app.UIAxes_PZ);     % Clear pole-zero plot
        end

        % Button pushed function: PlayButton
        function PlayButtonPushed(app, event)
            if isempty(app.y) || isempty(app.fs)
        uialert(app.UIFigure, 'Signal or sampling frequency not set.', 'Playback Error');
        return;
    end

    sound(app.y, app.fs);
    disp("Playing signal ...");
   
        end

        % Button pushed function: ResetButton
        function ResetButtonPushed2(app, event)
             % Clear all axes
            cla(app.InputTimeAxes);
            cla(app.OutputTimeAxes);
            cla(app.InputFreqAxes);
            cla(app.OutputFreqAxes);
             app.BandGains = ones(1,9);
            app.gain_170.Value = 0;
            app.gain_300.Value = 0;
            app.gain_610.Value = 0;
            app.gain_1005.Value = 0;
            app.gain_3k.Value = 0;
            app.gain_6k.Value = 0;
            app.gain_12k.Value = 0;
            app.gain_14k.Value = 0;
            app.gain_20k.Value = 0;
            cla(app.UIAxes_Mag);    % Clear magnitude response plot
            cla(app.UIAxes_Phase);  % Clear phase response plot
            cla(app.UIAxes_Impulse);% Clear impulse response plot
            cla(app.UIAxes_Impulse);   % Clear step response plot
            cla(app.UIAxes_PZ);     % Clear pole-zero plot
            % Reset output signal
            app.OutputSignal = [];  
              % Display reset confirmation
            disp('Application reset: Output signal cleared, gains zeroed.');

        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.BrowseButton = uibutton(app.UIFigure, 'push');
            app.BrowseButton.ButtonPushedFcn = createCallbackFcn(app, @BrowseButtonPushed, true);
            app.BrowseButton.Text = 'Browse';
            app.FilterTypeButtonGroup = uibuttongroup(app.UIFigure);
            app.FilterTypeButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @FilterTypeButtonGroupSelectionChanged, true);
            app.FilterTypeButtonGroup.Title = 'Filter Type';
            app.FIRButton = uiradiobutton(app.FilterTypeButtonGroup);
            app.FIRButton.Text = 'FIR';
            app.FIRButton.Value = true;
            app.IIRButton = uiradiobutton(app.FilterTypeButtonGroup);
            app.IIRButton.Text = 'IIR';
            app.ApplyButton = uibutton(app.UIFigure, 'push');
            app.ApplyButton.ButtonPushedFcn = createCallbackFcn(app, @ApplyButtonPushed, true);
            app.ApplyButton.Text = 'Apply';
            app.SaveButton = uibutton(app.UIFigure, 'push');
            app.SaveButton.ButtonPushedFcn = createCallbackFcn(app, @SaveButtonPushed, true);
            app.SaveButton.Text = 'Save';
            app.Panel = uipanel(app.UIFigure);
            app.FrequencyBandGainsPanel = uipanel(app.Panel);
            app.FrequencyBandGainsPanel.Title = 'Frequency Band Gains';
            app.PlotButton = uibutton(app.FrequencyBandGainsPanel, 'push');
            app.PlotButton.ButtonPushedFcn = createCallbackFcn(app, @PlotButtonPushed, true);
            app.PlotButton.Text = 'Plot';
            app.HzLabel_3 = uilabel(app.FrequencyBandGainsPanel);
            app.HzLabel_3.Text = '800-1200Hz';
            app.gain_1005 = uislider(app.FrequencyBandGainsPanel);
            app.gain_1005.Limits = [-12 12];
            app.gain_1005.MajorTicks = [-12 -9 -6 -3 0 3 6 9 12];
            app.gain_1005.MajorTickLabels = {'-12,', '-9,', '-6,', '-3,', '0,', '3,', '6,', '9,', '12'};
            app.gain_1005.MinorTicks = [-12 -11.4 -10.8 -10.2 -9.6 -9 -8.4 -7.8 -7.2 -6.6 -6 -5.4 -4.8 -4.2 -3.6 -3 -2.4 -1.8 -1.2 -0.6 0 0.6 1.2 1.8 2.4 3 3.6 4.2 4.8 5.4 6 6.6 7.2 7.8 8.4 9 9.6 10.2 10.8 11.4 12];
            app.KHzLabel = uilabel(app.FrequencyBandGainsPanel);
            app.KHzLabel.Text = '1.2-3 KHz';
            app.gain_3k = uislider(app.FrequencyBandGainsPanel);
            app.gain_3k.Limits = [-12 12];
            app.ResetButton = uibutton(app.FrequencyBandGainsPanel, 'push');
            app.ResetButton.ButtonPushedFcn = createCallbackFcn(app, @ResetButtonPushed2, true);
            app.ResetButton.Text = 'Reset';
            app.KhzLabel = uilabel(app.FrequencyBandGainsPanel);
            app.KhzLabel.Text = '12-16Khz';
            app.gain_14k = uislider(app.FrequencyBandGainsPanel);
            app.gain_14k.Limits = [-12 12];
            app.KHzLabel_2 = uilabel(app.FrequencyBandGainsPanel);
            app.KHzLabel_2.Text = '6-12 KHz';
            app.gain_12k = uislider(app.FrequencyBandGainsPanel);
            app.gain_12k.Limits = [-12 12];
            app.KHzLabel_3 = uilabel(app.FrequencyBandGainsPanel);
            app.KHzLabel_3.Text = '3-6 KHz';
            app.gain_6k = uislider(app.FrequencyBandGainsPanel);
            app.gain_6k.Limits = [-12 12];
            app.HzLabel_2 = uilabel(app.FrequencyBandGainsPanel);
            app.HzLabel_2.Text = '500-800Hz';
            app.gain_610 = uislider(app.FrequencyBandGainsPanel);
            app.gain_610.Limits = [-12 12];
            app.Label = uilabel(app.FrequencyBandGainsPanel);
            app.Label.Text = '200-500Hz';
            app.gain_300 = uislider(app.FrequencyBandGainsPanel);
            app.gain_300.Limits = [-12 12];
            app.PlotsPanel = uipanel(app.Panel);
            app.PlotsPanel.Title = 'Plots';
            app.GridLayout = uigridlayout(app.PlotsPanel);
            app.UIAxes_Mag = uiaxes(app.GridLayout);
            title(app.UIAxes_Mag, 'Title')
            xlabel(app.UIAxes_Mag, 'X')
            ylabel(app.UIAxes_Mag, 'Y')
            zlabel(app.UIAxes_Mag, 'Z')
            app.UIAxes_Phase = uiaxes(app.GridLayout);
            title(app.UIAxes_Phase, 'Title')
            xlabel(app.UIAxes_Phase, 'X')
            ylabel(app.UIAxes_Phase, 'Y')
            zlabel(app.UIAxes_Phase, 'Z')
            app.UIAxes_Impulse = uiaxes(app.GridLayout);
            title(app.UIAxes_Impulse, 'Title')
            xlabel(app.UIAxes_Impulse, 'X')
            ylabel(app.UIAxes_Impulse, 'Y')
            zlabel(app.UIAxes_Impulse, 'Z')
            app.UIAxes_PZ = uiaxes(app.GridLayout);
            title(app.UIAxes_PZ, 'Title')
            xlabel(app.UIAxes_PZ, 'X')
            ylabel(app.UIAxes_PZ, 'Y')
            zlabel(app.UIAxes_PZ, 'Z')
            app.HzLabel = uilabel(app.Panel);
            app.HzLabel.Text = '0-200Hz';
            app.gain_170 = uislider(app.Panel);
            app.gain_170.Limits = [-12 12];
            app.KHzLabel_4 = uilabel(app.Panel);
            app.KHzLabel_4.Text = '16-20 KHz';
            app.gain_20k = uislider(app.Panel);
            app.gain_20k.Limits = [-12 12];
            app.EnterButton = uibutton(app.UIFigure, 'push');
            app.EnterButton.ButtonPushedFcn = createCallbackFcn(app, @EnterButtonPushed, true);
            app.EnterButton.Text = 'Enter';
            app.ModeButtonGroup = uibuttongroup(app.UIFigure);
            app.ModeButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @ModeButtonGroupSelectionChanged, true);
            app.ModeButtonGroup.Title = 'Mode';
            app.StandardButton = uiradiobutton(app.ModeButtonGroup);
            app.StandardButton.Text = 'Standard';
            app.StandardButton.Value = true;
            app.CustomButton = uiradiobutton(app.ModeButtonGroup);
            app.CustomButton.Text = 'Custom';
            app.halfFsButton = uibutton(app.UIFigure, 'push');
            app.halfFsButton.ButtonPushedFcn = createCallbackFcn(app, @halfFsButtonPushed, true);
            app.halfFsButton.Text = 'half Fs';
            app.doubleFsButton = uibutton(app.UIFigure, 'push');
            app.doubleFsButton.ButtonPushedFcn = createCallbackFcn(app, @doubleFsButtonPushed, true);
            app.doubleFsButton.Text = 'double Fs';
            app.PlayButton = uibutton(app.UIFigure, 'push');
            app.PlayButton.ButtonPushedFcn = createCallbackFcn(app, @PlayButtonPushed, true);
            app.PlayButton.Text = 'Play';
            app.LocationEditFieldLabel = uilabel(app.UIFigure);
            app.LocationEditFieldLabel.Text = 'Location';
            app.LocationEditField = uieditfield(app.UIFigure, 'text');
            app.BandtoPlotDropDownLabel = uilabel(app.UIFigure);
            app.BandtoPlotDropDownLabel.Text = {'Band to Plot'; ''};
            app.BandtoPlotDropDown = uidropdown(app.UIFigure);
            app.BandtoPlotDropDown.Items = {'0-200 Hz', '200-500 Hz', '500-800 Hz', '800-1200 Hz', '1.2-3 KHz', '3-6 KHz', '6-12 KHz', '12-16 KHz', '16-20 KHz'};
            app.BandtoPlotDropDown.ItemsData = {'1', '2', '3', '4', '5', '6', '7', '8', '9'};
            app.BandtoPlotDropDown.ValueChangedFcn = createCallbackFcn(app, @BandtoPlotDropDownValueChanged, true);
            app.BandtoPlotDropDown.Value = '3';
            app.SamplingRateEditField_2Label = uilabel(app.UIFigure);
            app.SamplingRateEditField_2Label.Text = 'Sampling Rate';
            app.SamplingRateEditField_2 = uieditfield(app.UIFigure, 'numeric');
            app.SamplingRateEditField_2.ValueChangedFcn = createCallbackFcn(app, @SamplingRateEditField_2ValueChanged2, true);
            app.AxisPanel = uipanel(app.UIFigure);
            app.AxisPanel.Title = 'Axis';
            app.InputTimeAxes = uiaxes(app.AxisPanel);
            title(app.InputTimeAxes, 'Input Signal - Time Domain')
            xlabel(app.InputTimeAxes, 'X')
            ylabel(app.InputTimeAxes, 'Y')
            zlabel(app.InputTimeAxes, 'Z')
            app.OutputTimeAxes = uiaxes(app.AxisPanel);
            title(app.OutputTimeAxes, 'Output Signal - Time Domain')
            xlabel(app.OutputTimeAxes, 'X')
            ylabel(app.OutputTimeAxes, 'Y')
            zlabel(app.OutputTimeAxes, 'Z')
            app.InputFreqAxes = uiaxes(app.AxisPanel);
            title(app.InputFreqAxes, 'Input Signal - Frequency Domain')
            xlabel(app.InputFreqAxes, 'X')
            ylabel(app.InputFreqAxes, 'Y')
            zlabel(app.InputFreqAxes, 'Z')
            app.OutputFreqAxes = uiaxes(app.AxisPanel);
            title(app.OutputFreqAxes, 'Output Signal - Frequency Domain')
            xlabel(app.OutputFreqAxes, 'X')
            ylabel(app.OutputFreqAxes, 'Y')
            zlabel(app.OutputFreqAxes, 'Z')
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Audio_Equalizer

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end